classdef RogowskiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RogowskiAppUIFigure    matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        RogowskiTab            matlab.ui.container.Tab
        Panel_17               matlab.ui.container.Panel
        Label_2                matlab.ui.control.Label
        BestRs                 matlab.ui.control.EditField
        R0Label                matlab.ui.control.Label
        R0                     matlab.ui.control.EditField
        L0HLabel               matlab.ui.control.Label
        L0                     matlab.ui.control.EditField
        C0FLabel               matlab.ui.control.Label
        C0                     matlab.ui.control.EditField
        RsLabel                matlab.ui.control.Label
        Rs                     matlab.ui.control.EditField
        MHLabel                matlab.ui.control.Label
        M                      matlab.ui.control.EditField
        Panel_18               matlab.ui.container.Panel
        NEditFieldLabel        matlab.ui.control.Label
        N                      matlab.ui.control.EditField
        hmmEditFieldLabel      matlab.ui.control.Label
        h                      matlab.ui.control.EditField
        mEditFieldLabel        matlab.ui.control.Label
        Resistivity            matlab.ui.control.EditField
        mmLabel                matlab.ui.control.Label
        a                      matlab.ui.control.EditField
        mLabel                 matlab.ui.control.Label
        Cu                     matlab.ui.control.EditField
        mmEditField_2Label     matlab.ui.control.Label
        d                      matlab.ui.control.EditField
        mmEditField_2Label_2   matlab.ui.control.Label
        D                      matlab.ui.control.EditField
        lmmEditFieldLabel      matlab.ui.control.Label
        l                      matlab.ui.control.EditField
        Conv                   matlab.ui.control.Button
        RCIntegralTab          matlab.ui.container.Tab
        Panel                  matlab.ui.container.Panel
        RCBode                 matlab.ui.control.Button
        REditFieldLabel_4      matlab.ui.control.Label
        RCR                    matlab.ui.control.EditField
        CFEditFieldLabel_4     matlab.ui.control.Label
        RCC                    matlab.ui.control.EditField
        HOLDSwitch_3Label      matlab.ui.control.Label
        HOLDSwitch             matlab.ui.control.Switch
        Panel_2                matlab.ui.container.Panel
        LradsLabel             matlab.ui.control.Label
        OmegaL                 matlab.ui.control.EditField
        HradsLabel             matlab.ui.control.Label
        OmegaH                 matlab.ui.control.EditField
        Label_3                matlab.ui.control.Label
        Sensitivity            matlab.ui.control.EditField
        Label_5                matlab.ui.control.Label
        Bandwidth              matlab.ui.control.EditField
        Panel_3                matlab.ui.container.Panel
        RCMagAxes              matlab.ui.control.UIAxes
        RCPhaseAxes            matlab.ui.control.UIAxes
        ActiveIntegralTab      matlab.ui.container.Tab
        Panel_4                matlab.ui.container.Panel
        AIBode                 matlab.ui.control.Button
        REditFieldLabel_2      matlab.ui.control.Label
        AIR                    matlab.ui.control.EditField
        CFEditFieldLabel_2     matlab.ui.control.Label
        AIC                    matlab.ui.control.EditField
        R2Label                matlab.ui.control.Label
        R2                     matlab.ui.control.EditField
        R3Label                matlab.ui.control.Label
        R3                     matlab.ui.control.EditField
        R4Label                matlab.ui.control.Label
        R4                     matlab.ui.control.EditField
        R5Label                matlab.ui.control.Label
        R5                     matlab.ui.control.EditField
        C1FLabel               matlab.ui.control.Label
        C1                     matlab.ui.control.EditField
        C2FLabel               matlab.ui.control.Label
        C2                     matlab.ui.control.EditField
        RfLabel                matlab.ui.control.Label
        Rf                     matlab.ui.control.EditField
        HOLDSwitchLabel        matlab.ui.control.Label
        HOLDSwitch_2           matlab.ui.control.Switch
        Panel_5                matlab.ui.container.Panel
        LradsLabel_2           matlab.ui.control.Label
        OmegaL_2               matlab.ui.control.EditField
        HradsLabel_2           matlab.ui.control.Label
        OmegaH_2               matlab.ui.control.EditField
        Label_4                matlab.ui.control.Label
        Sensitivity_2          matlab.ui.control.EditField
        Label_6                matlab.ui.control.Label
        Bandwidth_2            matlab.ui.control.EditField
        Panel_6                matlab.ui.container.Panel
        AIMagAxes              matlab.ui.control.UIAxes
        AIPhaseAxes            matlab.ui.control.UIAxes
        CompareTab             matlab.ui.container.Tab
        CompareTabGroup        matlab.ui.container.TabGroup
        IntegralTab            matlab.ui.container.Tab
        Panel_7                matlab.ui.container.Panel
        CompareIntegral        matlab.ui.control.Button
        REditFieldLabel_3      matlab.ui.control.Label
        CR                     matlab.ui.control.EditField
        CFEditFieldLabel_3     matlab.ui.control.Label
        CC                     matlab.ui.control.EditField
        Panel_8                matlab.ui.container.Panel
        CompareMagAxes         matlab.ui.control.UIAxes
        ComparePhaseAxes       matlab.ui.control.UIAxes
        RCPanel                matlab.ui.container.Panel
        LradsLabel_3           matlab.ui.control.Label
        OmegaL_3               matlab.ui.control.EditField
        HradsLabel_3           matlab.ui.control.Label
        OmegaH_3               matlab.ui.control.EditField
        Label_7                matlab.ui.control.Label
        Sensitivity_3          matlab.ui.control.EditField
        Label_8                matlab.ui.control.Label
        Bandwidth_3            matlab.ui.control.EditField
        Panel_10               matlab.ui.container.Panel
        LradsLabel_4           matlab.ui.control.Label
        OmegaL_4               matlab.ui.control.EditField
        HradsLabel_4           matlab.ui.control.Label
        OmegaH_4               matlab.ui.control.EditField
        Label_9                matlab.ui.control.Label
        Sensitivity_4          matlab.ui.control.EditField
        Label_10               matlab.ui.control.Label
        Bandwidth_4            matlab.ui.control.EditField
        ActiveIntegralBodeTab  matlab.ui.container.Tab
        Panel_11               matlab.ui.container.Panel
        RfEditFieldLabel       matlab.ui.control.Label
        BodeRf                 matlab.ui.control.EditField
        BodeAdd                matlab.ui.control.Button
        BodeDel                matlab.ui.control.Button
        BodeRfList             matlab.ui.control.ListBox
        CompareBode            matlab.ui.control.Button
        Panel_12               matlab.ui.container.Panel
        CompareMagAxes_A       matlab.ui.control.UIAxes
        ComparePhaseAxes_A     matlab.ui.control.UIAxes
        StepTab                matlab.ui.container.Tab
        Panel_13               matlab.ui.container.Panel
        StepRfList             matlab.ui.control.ListBox
        RfEditFieldLabel_2     matlab.ui.control.Label
        StepRf                 matlab.ui.control.EditField
        StepAdd                matlab.ui.control.Button
        CompareStep            matlab.ui.control.Button
        StepDel                matlab.ui.control.Button
        Panel_14               matlab.ui.container.Panel
        CompareStepAxes        matlab.ui.control.UIAxes
        ImpulseTab             matlab.ui.container.Tab
        Panel_15               matlab.ui.container.Panel
        RsLabel_2              matlab.ui.control.Label
        ImpulseRs              matlab.ui.control.EditField
        ImpulseRsList          matlab.ui.control.ListBox
        ImpulseAdd             matlab.ui.control.Button
        ImpulseDel             matlab.ui.control.Button
        CompareImpulse         matlab.ui.control.Button
        Panel_16               matlab.ui.container.Panel
        CompareImpulseAxes     matlab.ui.control.UIAxes
        Tab                    matlab.ui.container.Tab
        TabGroup2              matlab.ui.container.TabGroup
        Tab_2                  matlab.ui.container.Tab
        Image                  matlab.ui.control.Image
        Image4                 matlab.ui.control.Image
        Image5                 matlab.ui.control.Image
        RCTab                  matlab.ui.container.Tab
        Image2                 matlab.ui.control.Image
        Tab_3                  matlab.ui.container.Tab
        Image3                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        Rogowski = Rogowski                     % 罗氏线圈
        RCIntegral = RCIntegral                 % 普通RC积分
        ActiveIntegral = ActiveIntegral         % 有源积分
    end
    
    methods (Access = private)
        
        function [] = SetData(app)
            % 罗氏线圈参数设定
            app.Rogowski.d = str2double(app.d.Value) * 1e-3;
            app.Rogowski.D = str2double(app.D.Value) * 1e-3;
            app.Rogowski.h = str2double(app.h.Value) * 1e-3;
            app.Rogowski.a = str2double(app.a.Value) * 1e-3;
            app.Rogowski.Resistivity = str2double(app.Resistivity.Value);
            app.Rogowski.Cu = str2double(app.Cu.Value) * 1e-6;
            app.Rogowski.N = str2double(app.N.Value);
            app.Rogowski.l = str2double(app.l.Value) * 1e-3;
            app.Rogowski.R0 = str2double(app.R0.Value);
            app.Rogowski.L0 = str2double(app.L0.Value);
            app.Rogowski.C0 = str2double(app.C0.Value);
            app.Rogowski.Rs = str2double(app.Rs.Value);
            app.Rogowski.M = str2double(app.M.Value);
            
            % 普通RC积分参数设定
            app.RCIntegral.Rogowski = app.Rogowski;
            app.RCIntegral.R = str2double(app.RCR.Value);
            app.RCIntegral.C = str2double(app.RCC.Value);
            
            % 有源积分参数设定
            app.ActiveIntegral.Rogowski = app.Rogowski;
            app.ActiveIntegral.R = str2double(app.AIR.Value);
            app.ActiveIntegral.C = str2double(app.AIC.Value);
            app.ActiveIntegral.C1 = str2double(app.C1.Value);
            app.ActiveIntegral.C2 = str2double(app.C2.Value);
            app.ActiveIntegral.R2 = str2double(app.R2.Value);
            app.ActiveIntegral.R3 = str2double(app.R3.Value);
            app.ActiveIntegral.R4 = str2double(app.R4.Value);
            app.ActiveIntegral.R5 = str2double(app.R5.Value);
            app.ActiveIntegral.Rf = str2double(app.Rf.Value);
        end
        
        function [] = UpdateCalc(app)
            app.BestRs.Value = num2str(app.Rogowski.CalcBestRs, '%10.5e');
            app.OmegaH.Value = num2str(app.RCIntegral.CalcOmegaH, '%10.5e');
            app.OmegaL.Value = num2str(app.RCIntegral.CalcOmegaL, '%10.5e');
            if app.RCIntegral.CalcOmegaL > 50*2*pi
                app.OmegaL.FontColor = [1 0 0];
            else
                app.OmegaL.FontColor = [0 0 0];
            end
            app.Bandwidth.Value = num2str(app.RCIntegral.CalcOmegaH - ...
                app.RCIntegral.CalcOmegaL, '%10.5e');
            app.Sensitivity.Value = num2str(app.RCIntegral.CalcSensitivity, '%10.5e');
            app.OmegaH_2.Value = num2str(app.ActiveIntegral.CalcOmegaH, '%10.5e');
            app.OmegaL_2.Value = num2str(app.ActiveIntegral.CalcOmegaL, '%10.5e');
            if app.ActiveIntegral.CalcOmegaL > 50*2*pi
                app.OmegaL_2.FontColor = [1 0 0];
            else
                app.OmegaL_2.FontColor = [0 0 0];
            end
            app.Bandwidth_2.Value = num2str(app.ActiveIntegral.CalcOmegaH - ...
                app.ActiveIntegral.CalcOmegaL, '%10.5e');
            app.Sensitivity_2.Value = num2str(app.ActiveIntegral.CalcSensitivity, '%10.5e');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ConvButtonPushed(app);
            RCBodeButtonPushed(app);
            AIBodeButtonPushed(app);
            CompareIntegralButtonPushed(app);
            CompareBodeButtonPushed(app);
            CompareStepPushed(app);
            CompareImpulsePushed(app);
        end

        % Value changing function: Rs
        function RsValueChanging(app, event)
            changingValue = event.Value;
            app.Rogowski.Rs = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: C0
        function C0ValueChanging(app, event)
            changingValue = event.Value;
            app.Rogowski.C0 = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: L0
        function L0ValueChanging(app, event)
            changingValue = event.Value;
            app.Rogowski.L0 = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: R0
        function R0ValueChanging(app, event)
            changingValue = event.Value;
            app.Rogowski.R0 = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: RCR
        function RCRValueChanging(app, event)
            changingValue = event.Value;
            app.RCIntegral.R = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: RCC
        function RCCValueChanging(app, event)
            changingValue = event.Value;
            app.RCIntegral.C = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: M
        function MValueChanging(app, event)
            changingValue = event.Value;
            app.Rogowski.M = str2double(changingValue);
            UpdateCalc(app);
        end

        % Button pushed function: RCBode
        function RCBodeButtonPushed(app, event)
            SetData(app);
            UpdateCalc(app);
            
            sys = app.RCIntegral.TransferFunction;
            [mag,phase,w] = bode(sys);
            
            Mag=20*log10(mag(1,1,:));
            semilogx(app.RCMagAxes,w,Mag(:));
            Phase=phase(1,1,:);
            semilogx(app.RCPhaseAxes,w,Phase(:));
        end

        % Value changing function: AIR
        function AIRValueChanging(app, event)
            changingValue = event.Value;
            app.ActiveIntegral.R = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: AIC
        function AICValueChanging(app, event)
            changingValue = event.Value;
            app.ActiveIntegral.C = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: R2
        function R2ValueChanging(app, event)
            changingValue = event.Value;
            app.ActiveIntegral.R2 = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: R3
        function R3ValueChanging(app, event)
            changingValue = event.Value;
            app.ActiveIntegral.R3 = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: R4
        function R4ValueChanging(app, event)
            changingValue = event.Value;
            app.ActiveIntegral.R4 = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: R5
        function R5ValueChanging(app, event)
            changingValue = event.Value;
            app.ActiveIntegral.R5 = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: C1
        function C1ValueChanging(app, event)
            changingValue = event.Value;
            app.ActiveIntegral.C1 = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: C2
        function C2ValueChanging(app, event)
            changingValue = event.Value;
            app.ActiveIntegral.C2 = str2double(changingValue);
            UpdateCalc(app);
        end

        % Value changing function: Rf
        function RfValueChanging(app, event)
            changingValue = event.Value;
            app.ActiveIntegral.Rf = str2double(changingValue);
            UpdateCalc(app);
        end

        % Button pushed function: AIBode
        function AIBodeButtonPushed(app, event)
            SetData(app);
            UpdateCalc(app);
            
            sys = app.ActiveIntegral.TransferFunction;
            [mag,phase,w] = bode(sys);
            
            Mag=20*log10(mag(1,1,:));
            semilogx(app.AIMagAxes,w,Mag(:));
            Phase=phase(1,1,:);
            semilogx(app.AIPhaseAxes,w,Phase(:));
        end

        % Button pushed function: CompareIntegral
        function CompareIntegralButtonPushed(app, event)
            SetData(app);
            
            app.RCIntegral.R = str2double(app.CR.Value);
            app.RCIntegral.C = str2double(app.CC.Value);
            
            sys = app.RCIntegral.TransferFunction;
            [mag,phase,w] = bode(sys);
            
            Mag=20*log10(mag(1,1,:));
            semilogx(app.CompareMagAxes,w,Mag(:));
            Phase=phase(1,1,:);
            semilogx(app.ComparePhaseAxes,w,Phase(:));
            
            hold(app.CompareMagAxes, 'on');
            hold(app.ComparePhaseAxes, 'on');
            
            app.ActiveIntegral.R = str2double(app.CR.Value);
            app.ActiveIntegral.C = str2double(app.CC.Value);
            
            sys = app.ActiveIntegral.TransferFunction;
            [mag,phase,w] = bode(sys);
            
            Mag=20*log10(mag(1,1,:));
            semilogx(app.CompareMagAxes,w,Mag(:));
            Phase=phase(1,1,:);
            semilogx(app.ComparePhaseAxes,w,Phase(:));
            
            legend(app.CompareMagAxes,'普通RC积分','有源积分');
            
            hold(app.CompareMagAxes, 'off');
            hold(app.ComparePhaseAxes, 'off');
            
            app.OmegaH_3.Value = num2str(app.RCIntegral.CalcOmegaH, '%10.5e');
            app.OmegaL_3.Value = num2str(app.RCIntegral.CalcOmegaL, '%10.5e');
            app.Bandwidth_3.Value = num2str(app.RCIntegral.CalcOmegaH - ...
                app.RCIntegral.CalcOmegaL, '%10.5e');
            app.Sensitivity_3.Value = num2str(app.RCIntegral.CalcSensitivity, '%10.5e');
            app.OmegaH_4.Value = num2str(app.ActiveIntegral.CalcOmegaH, '%10.5e');
            app.OmegaL_4.Value = num2str(app.ActiveIntegral.CalcOmegaL, '%10.5e');
            app.Bandwidth_4.Value = num2str(app.ActiveIntegral.CalcOmegaH - ...
                app.ActiveIntegral.CalcOmegaL, '%10.5e');
            app.Sensitivity_4.Value = num2str(app.ActiveIntegral.CalcSensitivity, '%10.5e');
        end

        % Button pushed function: BodeAdd
        function BodeAddButtonPushed(app, event)
            app.BodeRfList.Items{end + 1} = app.BodeRf.Value;
            app.BodeRf.Value = "";
        end

        % Button pushed function: BodeDel
        function BodeDelButtonPushed(app, event)
            [~,idx] = ismember(app.BodeRfList.Value,app.BodeRfList.Items);
            app.BodeRfList.Items(idx) = [];
        end

        % Button pushed function: CompareBode
        function CompareBodeButtonPushed(app, event)
            [~, s] = size(app.BodeRfList.Items);
            
            app.ActiveIntegral.R = str2double(app.CR.Value);
            app.ActiveIntegral.C = str2double(app.CC.Value);
            
            str = string.empty;
            for i = 1:s
                app.ActiveIntegral.Rf = str2double(app.BodeRfList.Items{i});
                
                sys = app.ActiveIntegral.TransferFunction;
                [mag,phase,w] = bode(sys);
                
                Mag=20*log10(mag(1,1,:));
                semilogx(app.CompareMagAxes_A,w,Mag(:));
                Phase=phase(1,1,:);
                semilogx(app.ComparePhaseAxes_A,w,Phase(:));
                
                str(i) = ['Rf = ', app.BodeRfList.Items{i}];
                
                hold(app.CompareMagAxes_A, 'on');
                hold(app.ComparePhaseAxes_A, 'on');
            end
            
            legend(app.CompareMagAxes_A,str);
            hold(app.CompareMagAxes_A, 'off');
            hold(app.ComparePhaseAxes_A, 'off');
        end

        % Button pushed function: StepAdd
        function StepAddButtonPushed(app, event)
            app.StepRfList.Items{end + 1} = app.StepRf.Value;
            app.StepRf.Value = "";
        end

        % Button pushed function: StepDel
        function StepDelButtonPushed(app, event)
            [~,idx] = ismember(app.StepRfList.Value,app.StepRfList.Items);
            app.StepRfList.Items(idx) = [];
        end

        % Button pushed function: CompareStep
        function CompareStepPushed(app, event)
            [~, s] = size(app.StepRfList.Items);
            
            app.ActiveIntegral.R = str2double(app.CR.Value);
            app.ActiveIntegral.C = str2double(app.CC.Value);
            
            str = string.empty;
            for i = 1:s
                app.ActiveIntegral.Rf = str2double(app.StepRfList.Items{i});
                
                sys = app.ActiveIntegral.TransferFunction;
                [y, t] = step(sys);
                
                plot(app.CompareStepAxes, t, y);
                
                str(i) = ['Rf = ', app.StepRfList.Items{i}];
                
                hold(app.CompareStepAxes, 'on');
            end
            
            legend(app.CompareStepAxes,str);
            hold(app.CompareStepAxes, 'off');
        end

        % Button pushed function: ImpulseAdd
        function ImpulseAddButtonPushed(app, event)
            app.ImpulseRsList.Items{end + 1} = app.ImpulseRs.Value;
            app.ImpulseRs.Value = "";
        end

        % Button pushed function: ImpulseDel
        function ImpulseDelButtonPushed(app, event)
            [~,idx] = ismember(app.ImpulseRsList.Value,app.ImpulseRsList.Items);
            app.ImpulseRsList.Items(idx) = [];
        end

        % Button pushed function: CompareImpulse
        function CompareImpulsePushed(app, event)
            [~, s] = size(app.ImpulseRsList.Items);
            
            app.ActiveIntegral.R = str2double(app.CR.Value);
            app.ActiveIntegral.C = str2double(app.CC.Value);
            
            str = string.empty;
            for i = 1:s
                app.ActiveIntegral.Rogowski.Rs = str2double(app.ImpulseRsList.Items{i});
                
                sys = app.ActiveIntegral.TransferFunction;
                [y, t] = impulse(sys);
                
                plot(app.CompareImpulseAxes, t, y);
                
                str(i) = ['Rs = ', app.ImpulseRsList.Items{i}];
                
                hold(app.CompareImpulseAxes, 'on');
            end
            
            legend(app.CompareImpulseAxes,str);
            hold(app.CompareImpulseAxes, 'off');
        end

        % Value changed function: HOLDSwitch_2
        function HOLDSwitch_2ValueChanged(app, event)
            value = app.HOLDSwitch_2.Value;
            if value == "OFF"
                hold(app.AIMagAxes, "off");
                hold(app.AIPhaseAxes, "off");
            elseif value == "ON"
                hold(app.AIMagAxes, "on");
                hold(app.AIPhaseAxes, "on");
            end
        end

        % Value changed function: HOLDSwitch
        function HOLDSwitchValueChanged(app, event)
            value = app.HOLDSwitch.Value;
            if value == "OFF"
                hold(app.RCMagAxes, "off");
                hold(app.RCPhaseAxes, "off");
            elseif value == "ON"
                hold(app.RCMagAxes, "on");
                hold(app.RCPhaseAxes, "on");
            end
        end

        % Button pushed function: Conv
        function ConvButtonPushed(app, event)
            SetData(app);
            app.Rogowski.CalcElecData;
            app.R0.Value = num2str(app.Rogowski.R0);
            app.L0.Value = num2str(app.Rogowski.L0);
            app.C0.Value = num2str(app.Rogowski.C0);
            app.M.Value = num2str(app.Rogowski.M);
            UpdateCalc(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RogowskiAppUIFigure = uifigure('Visible', 'off');
            app.RogowskiAppUIFigure.Name = 'Rogowski App';
            app.TabGroup = uitabgroup(app.RogowskiAppUIFigure);
            app.RogowskiTab = uitab(app.TabGroup);
            app.RogowskiTab.Title = '线圈参数';
            app.Panel_17 = uipanel(app.RogowskiTab);
            app.Panel_17.Title = '电气参数';
            app.Label_2 = uilabel(app.Panel_17);
            app.Label_2.Text = '最佳取样电阻/Ω';
            app.BestRs = uieditfield(app.Panel_17, 'text');
            app.BestRs.Editable = 'off';
            app.R0Label = uilabel(app.Panel_17);
            app.R0Label.Text = '线圈内阻R0/Ω';
            app.R0 = uieditfield(app.Panel_17, 'text');
            app.R0.ValueChangingFcn = createCallbackFcn(app, @R0ValueChanging, true);
            app.R0.Value = '3';
            app.L0HLabel = uilabel(app.Panel_17);
            app.L0HLabel.Text = '线圈自感L0/H';
            app.L0 = uieditfield(app.Panel_17, 'text');
            app.L0.ValueChangingFcn = createCallbackFcn(app, @L0ValueChanging, true);
            app.L0.Value = '303e-6';
            app.C0FLabel = uilabel(app.Panel_17);
            app.C0FLabel.Text = '分布电容C0/F';
            app.C0 = uieditfield(app.Panel_17, 'text');
            app.C0.ValueChangingFcn = createCallbackFcn(app, @C0ValueChanging, true);
            app.C0.Value = '323.7e-12';
            app.RsLabel = uilabel(app.Panel_17);
            app.RsLabel.Text = '取样电阻Rs/Ω';
            app.Rs = uieditfield(app.Panel_17, 'text');
            app.Rs.ValueChangingFcn = createCallbackFcn(app, @RsValueChanging, true);
            app.Rs.Value = '60e3';
            app.MHLabel = uilabel(app.Panel_17);
            app.MHLabel.Text = '线圈互感M/H';
            app.M = uieditfield(app.Panel_17, 'text');
            app.M.ValueChangingFcn = createCallbackFcn(app, @MValueChanging, true);
            app.M.Value = '0.4584e-6';
            app.Panel_18 = uipanel(app.RogowskiTab);
            app.Panel_18.Title = '机械参数';
            app.NEditFieldLabel = uilabel(app.Panel_18);
            app.NEditFieldLabel.Text = '匝数N';
            app.N = uieditfield(app.Panel_18, 'text');
            app.N.Value = '80';
            app.hmmEditFieldLabel = uilabel(app.Panel_18);
            app.hmmEditFieldLabel.Text = '高度h/mm';
            app.h = uieditfield(app.Panel_18, 'text');
            app.h.Value = '3.2';
            app.mEditFieldLabel = uilabel(app.Panel_18);
            app.mEditFieldLabel.Text = '电阻率/Ω·m';
            app.Resistivity = uieditfield(app.Panel_18, 'text');
            app.Resistivity.Value = '1.7e-8';
            app.mmLabel = uilabel(app.Panel_18);
            app.mmLabel.Text = '布线宽度/mm';
            app.a = uieditfield(app.Panel_18, 'text');
            app.a.Value = '0.254';
            app.mLabel = uilabel(app.Panel_18);
            app.mLabel.Text = '铜箔厚度/μm';
            app.Cu = uieditfield(app.Panel_18, 'text');
            app.Cu.Value = '35';
            app.mmEditField_2Label = uilabel(app.Panel_18);
            app.mmEditField_2Label.Text = '内直径/mm';
            app.d = uieditfield(app.Panel_18, 'text');
            app.d.Value = '33';
            app.mmEditField_2Label_2 = uilabel(app.Panel_18);
            app.mmEditField_2Label_2.Text = '外直径/mm';
            app.D = uieditfield(app.Panel_18, 'text');
            app.D.Value = '54';
            app.lmmEditFieldLabel = uilabel(app.Panel_18);
            app.lmmEditFieldLabel.Text = '匝间距离l/mm';
            app.l = uieditfield(app.Panel_18, 'text');
            app.l.Value = '1.5';
            app.Conv = uibutton(app.RogowskiTab, 'push');
            app.Conv.ButtonPushedFcn = createCallbackFcn(app, @ConvButtonPushed, true);
            app.Conv.Text = '>';
            app.RCIntegralTab = uitab(app.TabGroup);
            app.RCIntegralTab.Title = '普通RC积分';
            app.Panel = uipanel(app.RCIntegralTab);
            app.Panel.Title = '参数';
            app.RCBode = uibutton(app.Panel, 'push');
            app.RCBode.ButtonPushedFcn = createCallbackFcn(app, @RCBodeButtonPushed, true);
            app.RCBode.Text = '更新伯德图';
            app.REditFieldLabel_4 = uilabel(app.Panel);
            app.REditFieldLabel_4.Text = '积分电阻R/Ω';
            app.RCR = uieditfield(app.Panel, 'text');
            app.RCR.ValueChangingFcn = createCallbackFcn(app, @RCRValueChanging, true);
            app.RCR.Value = '1e6';
            app.CFEditFieldLabel_4 = uilabel(app.Panel);
            app.CFEditFieldLabel_4.Text = '积分电容C/F';
            app.RCC = uieditfield(app.Panel, 'text');
            app.RCC.ValueChangingFcn = createCallbackFcn(app, @RCCValueChanging, true);
            app.RCC.Value = '1e-6';
            app.HOLDSwitch_3Label = uilabel(app.Panel);
            app.HOLDSwitch_3Label.Text = 'HOLD';
            app.HOLDSwitch = uiswitch(app.Panel, 'slider');
            app.HOLDSwitch.Items = {'OFF', 'ON'};
            app.HOLDSwitch.ValueChangedFcn = createCallbackFcn(app, @HOLDSwitchValueChanged, true);
            app.HOLDSwitch.Value = 'OFF';
            app.Panel_2 = uipanel(app.RCIntegralTab);
            app.Panel_2.Title = '信息';
            app.LradsLabel = uilabel(app.Panel_2);
            app.LradsLabel.Text = 'ωL/(rad/s)';
            app.OmegaL = uieditfield(app.Panel_2, 'text');
            app.OmegaL.Editable = 'off';
            app.HradsLabel = uilabel(app.Panel_2);
            app.HradsLabel.Text = 'ωH(rad/s)';
            app.OmegaH = uieditfield(app.Panel_2, 'text');
            app.OmegaH.Editable = 'off';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '灵敏度';
            app.Sensitivity = uieditfield(app.Panel_2, 'text');
            app.Sensitivity.Editable = 'off';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '带宽';
            app.Bandwidth = uieditfield(app.Panel_2, 'text');
            app.Bandwidth.Editable = 'off';
            app.Panel_3 = uipanel(app.RCIntegralTab);
            app.Panel_3.Title = '图像';
            app.RCMagAxes = uiaxes(app.Panel_3);
            title(app.RCMagAxes, 'Bode Diagram')
            xlabel(app.RCMagAxes, '')
            ylabel(app.RCMagAxes, 'Magnitude (dB)')
            app.RCPhaseAxes = uiaxes(app.Panel_3);
            title(app.RCPhaseAxes, '')
            xlabel(app.RCPhaseAxes, 'Frequency (rad/s)')
            ylabel(app.RCPhaseAxes, 'Phase (deg)')
            app.ActiveIntegralTab = uitab(app.TabGroup);
            app.ActiveIntegralTab.Title = '有源积分';
            app.Panel_4 = uipanel(app.ActiveIntegralTab);
            app.Panel_4.Title = '参数';
            app.AIBode = uibutton(app.Panel_4, 'push');
            app.AIBode.ButtonPushedFcn = createCallbackFcn(app, @AIBodeButtonPushed, true);
            app.AIBode.Text = '更新伯德图';
            app.REditFieldLabel_2 = uilabel(app.Panel_4);
            app.REditFieldLabel_2.Text = '积分电阻R/Ω';
            app.AIR = uieditfield(app.Panel_4, 'text');
            app.AIR.ValueChangingFcn = createCallbackFcn(app, @AIRValueChanging, true);
            app.AIR.Value = '20e3';
            app.CFEditFieldLabel_2 = uilabel(app.Panel_4);
            app.CFEditFieldLabel_2.Text = '积分电容C/F';
            app.AIC = uieditfield(app.Panel_4, 'text');
            app.AIC.ValueChangingFcn = createCallbackFcn(app, @AICValueChanging, true);
            app.AIC.Value = '0.3e-6';
            app.R2Label = uilabel(app.Panel_4);
            app.R2Label.Text = 'R2/Ω';
            app.R2 = uieditfield(app.Panel_4, 'text');
            app.R2.ValueChangingFcn = createCallbackFcn(app, @R2ValueChanging, true);
            app.R2.Value = '660';
            app.R3Label = uilabel(app.Panel_4);
            app.R3Label.Text = 'R3/Ω';
            app.R3 = uieditfield(app.Panel_4, 'text');
            app.R3.ValueChangingFcn = createCallbackFcn(app, @R3ValueChanging, true);
            app.R3.Value = '660';
            app.R4Label = uilabel(app.Panel_4);
            app.R4Label.Text = 'R4/Ω';
            app.R4 = uieditfield(app.Panel_4, 'text');
            app.R4.ValueChangingFcn = createCallbackFcn(app, @R4ValueChanging, true);
            app.R4.Value = '1e3';
            app.R5Label = uilabel(app.Panel_4);
            app.R5Label.Text = 'R5/Ω';
            app.R5 = uieditfield(app.Panel_4, 'text');
            app.R5.ValueChangingFcn = createCallbackFcn(app, @R5ValueChanging, true);
            app.R5.Value = '2e3';
            app.C1FLabel = uilabel(app.Panel_4);
            app.C1FLabel.Text = 'C1/F';
            app.C1 = uieditfield(app.Panel_4, 'text');
            app.C1.ValueChangingFcn = createCallbackFcn(app, @C1ValueChanging, true);
            app.C1.Value = '40e-6';
            app.C2FLabel = uilabel(app.Panel_4);
            app.C2FLabel.Text = 'C2/F';
            app.C2 = uieditfield(app.Panel_4, 'text');
            app.C2.ValueChangingFcn = createCallbackFcn(app, @C2ValueChanging, true);
            app.C2.Value = '40e-6';
            app.RfLabel = uilabel(app.Panel_4);
            app.RfLabel.Text = 'Rf/Ω';
            app.Rf = uieditfield(app.Panel_4, 'text');
            app.Rf.ValueChangingFcn = createCallbackFcn(app, @RfValueChanging, true);
            app.Rf.Value = '2e6';
            app.HOLDSwitchLabel = uilabel(app.Panel_4);
            app.HOLDSwitchLabel.Text = 'HOLD';
            app.HOLDSwitch_2 = uiswitch(app.Panel_4, 'slider');
            app.HOLDSwitch_2.Items = {'OFF', 'ON'};
            app.HOLDSwitch_2.ValueChangedFcn = createCallbackFcn(app, @HOLDSwitch_2ValueChanged, true);
            app.HOLDSwitch_2.Value = 'OFF';
            app.Panel_5 = uipanel(app.ActiveIntegralTab);
            app.Panel_5.Title = '信息';
            app.LradsLabel_2 = uilabel(app.Panel_5);
            app.LradsLabel_2.Text = 'ωL/(rad/s)';
            app.OmegaL_2 = uieditfield(app.Panel_5, 'text');
            app.OmegaL_2.Editable = 'off';
            app.HradsLabel_2 = uilabel(app.Panel_5);
            app.HradsLabel_2.Text = 'ωH(rad/s)';
            app.OmegaH_2 = uieditfield(app.Panel_5, 'text');
            app.OmegaH_2.Editable = 'off';
            app.Label_4 = uilabel(app.Panel_5);
            app.Label_4.Text = '灵敏度';
            app.Sensitivity_2 = uieditfield(app.Panel_5, 'text');
            app.Sensitivity_2.Editable = 'off';
            app.Label_6 = uilabel(app.Panel_5);
            app.Label_6.Text = '带宽';
            app.Bandwidth_2 = uieditfield(app.Panel_5, 'text');
            app.Bandwidth_2.Editable = 'off';
            app.Panel_6 = uipanel(app.ActiveIntegralTab);
            app.Panel_6.Title = '图像';
            app.AIMagAxes = uiaxes(app.Panel_6);
            title(app.AIMagAxes, 'Bode Diagram')
            xlabel(app.AIMagAxes, '')
            ylabel(app.AIMagAxes, 'Magnitude (dB)')
            app.AIPhaseAxes = uiaxes(app.Panel_6);
            title(app.AIPhaseAxes, '')
            xlabel(app.AIPhaseAxes, 'Frequency (rad/s)')
            ylabel(app.AIPhaseAxes, 'Phase (deg)')
            app.CompareTab = uitab(app.TabGroup);
            app.CompareTab.Title = '对比';
            app.CompareTabGroup = uitabgroup(app.CompareTab);
            app.IntegralTab = uitab(app.CompareTabGroup);
            app.IntegralTab.Title = '积分电路';
            app.Panel_7 = uipanel(app.IntegralTab);
            app.Panel_7.Title = '参数';
            app.CompareIntegral = uibutton(app.Panel_7, 'push');
            app.CompareIntegral.ButtonPushedFcn = createCallbackFcn(app, @CompareIntegralButtonPushed, true);
            app.CompareIntegral.Text = '对比积分电路';
            app.REditFieldLabel_3 = uilabel(app.Panel_7);
            app.REditFieldLabel_3.Text = '积分电阻R/Ω';
            app.CR = uieditfield(app.Panel_7, 'text');
            app.CR.Value = '20e3';
            app.CFEditFieldLabel_3 = uilabel(app.Panel_7);
            app.CFEditFieldLabel_3.Text = '积分电容C/F';
            app.CC = uieditfield(app.Panel_7, 'text');
            app.CC.Value = '0.3e-6';
            app.Panel_8 = uipanel(app.IntegralTab);
            app.Panel_8.Title = '图像';
            app.CompareMagAxes = uiaxes(app.Panel_8);
            title(app.CompareMagAxes, 'Bode Diagram')
            xlabel(app.CompareMagAxes, '')
            ylabel(app.CompareMagAxes, 'Magnitude (dB)')
            app.ComparePhaseAxes = uiaxes(app.Panel_8);
            title(app.ComparePhaseAxes, '')
            xlabel(app.ComparePhaseAxes, 'Frequency (rad/s)')
            ylabel(app.ComparePhaseAxes, 'Phase (deg)')
            app.RCPanel = uipanel(app.IntegralTab);
            app.RCPanel.Title = '普通RC积分';
            app.LradsLabel_3 = uilabel(app.RCPanel);
            app.LradsLabel_3.Text = 'ωL/(rad/s)';
            app.OmegaL_3 = uieditfield(app.RCPanel, 'text');
            app.OmegaL_3.Editable = 'off';
            app.HradsLabel_3 = uilabel(app.RCPanel);
            app.HradsLabel_3.Text = 'ωH(rad/s)';
            app.OmegaH_3 = uieditfield(app.RCPanel, 'text');
            app.OmegaH_3.Editable = 'off';
            app.Label_7 = uilabel(app.RCPanel);
            app.Label_7.Text = '灵敏度';
            app.Sensitivity_3 = uieditfield(app.RCPanel, 'text');
            app.Sensitivity_3.Editable = 'off';
            app.Label_8 = uilabel(app.RCPanel);
            app.Label_8.Text = '带宽';
            app.Bandwidth_3 = uieditfield(app.RCPanel, 'text');
            app.Bandwidth_3.Editable = 'off';
            app.Panel_10 = uipanel(app.IntegralTab);
            app.Panel_10.Title = '有源积分';
            app.LradsLabel_4 = uilabel(app.Panel_10);
            app.LradsLabel_4.Text = 'ωL/(rad/s)';
            app.OmegaL_4 = uieditfield(app.Panel_10, 'text');
            app.OmegaL_4.Editable = 'off';
            app.HradsLabel_4 = uilabel(app.Panel_10);
            app.HradsLabel_4.Text = 'ωH(rad/s)';
            app.OmegaH_4 = uieditfield(app.Panel_10, 'text');
            app.OmegaH_4.Editable = 'off';
            app.Label_9 = uilabel(app.Panel_10);
            app.Label_9.Text = '灵敏度';
            app.Sensitivity_4 = uieditfield(app.Panel_10, 'text');
            app.Sensitivity_4.Editable = 'off';
            app.Label_10 = uilabel(app.Panel_10);
            app.Label_10.Text = '带宽';
            app.Bandwidth_4 = uieditfield(app.Panel_10, 'text');
            app.Bandwidth_4.Editable = 'off';
            app.ActiveIntegralBodeTab = uitab(app.CompareTabGroup);
            app.ActiveIntegralBodeTab.Title = '有源积分伯德图';
            app.Panel_11 = uipanel(app.ActiveIntegralBodeTab);
            app.Panel_11.Title = '参数';
            app.RfEditFieldLabel = uilabel(app.Panel_11);
            app.RfEditFieldLabel.Text = 'Rf';
            app.BodeRf = uieditfield(app.Panel_11, 'text');
            app.BodeAdd = uibutton(app.Panel_11, 'push');
            app.BodeAdd.ButtonPushedFcn = createCallbackFcn(app, @BodeAddButtonPushed, true);
            app.BodeAdd.Text = '添加';
            app.BodeDel = uibutton(app.Panel_11, 'push');
            app.BodeDel.ButtonPushedFcn = createCallbackFcn(app, @BodeDelButtonPushed, true);
            app.BodeDel.Text = '删除';
            app.BodeRfList = uilistbox(app.Panel_11);
            app.BodeRfList.Items = {'0.5e6', '2e6', '4e6'};
            app.BodeRfList.Value = '0.5e6';
            app.CompareBode = uibutton(app.Panel_11, 'push');
            app.CompareBode.ButtonPushedFcn = createCallbackFcn(app, @CompareBodeButtonPushed, true);
            app.CompareBode.Text = '对比伯德图';
            app.Panel_12 = uipanel(app.ActiveIntegralBodeTab);
            app.Panel_12.Title = '图像';
            app.CompareMagAxes_A = uiaxes(app.Panel_12);
            title(app.CompareMagAxes_A, 'Bode Diagram')
            xlabel(app.CompareMagAxes_A, '')
            ylabel(app.CompareMagAxes_A, 'Magnitude (dB)')
            app.ComparePhaseAxes_A = uiaxes(app.Panel_12);
            title(app.ComparePhaseAxes_A, '')
            xlabel(app.ComparePhaseAxes_A, 'Frequency (rad/s)')
            ylabel(app.ComparePhaseAxes_A, 'Phase (deg)')
            app.StepTab = uitab(app.CompareTabGroup);
            app.StepTab.Title = '阶跃响应';
            app.Panel_13 = uipanel(app.StepTab);
            app.Panel_13.Title = '参数';
            app.StepRfList = uilistbox(app.Panel_13);
            app.StepRfList.Items = {'0.5e6', '2e6', '4e6'};
            app.StepRfList.Value = '0.5e6';
            app.RfEditFieldLabel_2 = uilabel(app.Panel_13);
            app.RfEditFieldLabel_2.Text = 'Rf';
            app.StepRf = uieditfield(app.Panel_13, 'text');
            app.StepAdd = uibutton(app.Panel_13, 'push');
            app.StepAdd.ButtonPushedFcn = createCallbackFcn(app, @StepAddButtonPushed, true);
            app.StepAdd.Text = '添加';
            app.CompareStep = uibutton(app.Panel_13, 'push');
            app.CompareStep.ButtonPushedFcn = createCallbackFcn(app, @CompareStepPushed, true);
            app.CompareStep.Text = '对比阶跃响应';
            app.StepDel = uibutton(app.Panel_13, 'push');
            app.StepDel.ButtonPushedFcn = createCallbackFcn(app, @StepDelButtonPushed, true);
            app.StepDel.Text = '删除';
            app.Panel_14 = uipanel(app.StepTab);
            app.Panel_14.Title = '图像';
            app.CompareStepAxes = uiaxes(app.Panel_14);
            title(app.CompareStepAxes, 'Step Response')
            xlabel(app.CompareStepAxes, 'Time (seconds)')
            ylabel(app.CompareStepAxes, 'Amplitude')
            app.ImpulseTab = uitab(app.CompareTabGroup);
            app.ImpulseTab.Title = '脉冲响应';
            app.Panel_15 = uipanel(app.ImpulseTab);
            app.Panel_15.Title = '参数';
            app.RsLabel_2 = uilabel(app.Panel_15);
            app.RsLabel_2.Text = 'Rs';
            app.ImpulseRs = uieditfield(app.Panel_15, 'text');
            app.ImpulseRsList = uilistbox(app.Panel_15);
            app.ImpulseRsList.Items = {'30e3', '60e3', '120e3'};
            app.ImpulseRsList.Value = '30e3';
            app.ImpulseAdd = uibutton(app.Panel_15, 'push');
            app.ImpulseAdd.ButtonPushedFcn = createCallbackFcn(app, @ImpulseAddButtonPushed, true);
            app.ImpulseAdd.Text = '添加';
            app.ImpulseDel = uibutton(app.Panel_15, 'push');
            app.ImpulseDel.ButtonPushedFcn = createCallbackFcn(app, @ImpulseDelButtonPushed, true);
            app.ImpulseDel.Text = '删除';
            app.CompareImpulse = uibutton(app.Panel_15, 'push');
            app.CompareImpulse.ButtonPushedFcn = createCallbackFcn(app, @CompareImpulsePushed, true);
            app.CompareImpulse.Text = '对比脉冲响应';
            app.Panel_16 = uipanel(app.ImpulseTab);
            app.Panel_16.Title = '图像';
            app.CompareImpulseAxes = uiaxes(app.Panel_16);
            title(app.CompareImpulseAxes, 'Impulse Response')
            xlabel(app.CompareImpulseAxes, 'Time (seconds)')
            ylabel(app.CompareImpulseAxes, 'Amplitude')
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '说明';
            app.TabGroup2 = uitabgroup(app.Tab);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = '罗氏线圈';
            app.Image = uiimage(app.Tab_2);
            app.Image.ImageSource = '罗氏线圈等效电路.png';
            app.Image4 = uiimage(app.Tab_2);
            app.Image4.ImageSource = '罗氏线圈实物图.png';
            app.Image5 = uiimage(app.Tab_2);
            app.Image5.ImageSource = '罗氏线圈截面示意图.png';
            app.RCTab = uitab(app.TabGroup2);
            app.RCTab.Title = '普通RC积分';
            app.Image2 = uiimage(app.RCTab);
            app.Image2.ImageSource = '普通RC积分电路.png';
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '有源积分';
            app.Image3 = uiimage(app.Tab_3);
            app.Image3.ImageSource = '有源积分电路.png';
            % Show the figure after all components are created
            app.RogowskiAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RogowskiApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RogowskiAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RogowskiAppUIFigure)
        end
    end
end